classdef UltrasoundClassificationv5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        currMicRecording                matlab.ui.control.UIAxes
        RecordButton                    matlab.ui.control.Button
        PlayButton                      matlab.ui.control.Button
        CurrentStateLabel               matlab.ui.control.Label
        fftGraph                        matlab.ui.control.UIAxes
        TabGroup                        matlab.ui.container.TabGroup
        OptionsTab                      matlab.ui.container.Tab
        ManualStartStopCheckBox         matlab.ui.control.CheckBox
        numTrialsEditField_2Label       matlab.ui.control.Label
        numTrialsEditField              matlab.ui.control.NumericEditField
        durTrialsEditField_2Label       matlab.ui.control.Label
        durTrialsEditField              matlab.ui.control.NumericEditField
        AdvancedTab                     matlab.ui.container.Tab
        SavePathEditField_2Label        matlab.ui.control.Label
        SavePathEditField               matlab.ui.control.EditField
        LoadPathEditField_2Label        matlab.ui.control.Label
        LoadPathEditField               matlab.ui.control.EditField
        ParametersEditField_2Label      matlab.ui.control.Label
        ParametersEditField             matlab.ui.control.EditField
        WeightsEditField_2Label         matlab.ui.control.Label
        WeightsEditField                matlab.ui.control.EditField
        ViewCodeEditFieldLabel          matlab.ui.control.Label
        ViewCodeEditField               matlab.ui.control.EditField
        TrainingTab                     matlab.ui.container.Tab
        DataFolderPathEditFieldLabel    matlab.ui.control.Label
        DataFolderPathEditField         matlab.ui.control.EditField
        TrainCodeEditFieldLabel         matlab.ui.control.Label
        TrainCodeEditField              matlab.ui.control.EditField
        ParametersPathEditFieldLabel    matlab.ui.control.Label
        ParametersPathEditField         matlab.ui.control.EditField
        ClicktoTrainButton              matlab.ui.control.Button
        TrainStatusLabel                matlab.ui.control.Label
        TabGroup2                       matlab.ui.container.TabGroup
        ResultsTab                      matlab.ui.container.Tab
        PatientHealthEditFieldLabel     matlab.ui.control.Label
        PatientHealthEditField          matlab.ui.control.EditField
        ConfidenceBoundsEditFieldLabel  matlab.ui.control.Label
        ConfidenceBoundsEditField       matlab.ui.control.EditField
        MoreTab                         matlab.ui.container.Tab
        TextArea                        matlab.ui.control.TextArea
    end


    properties (Access = private)
        patientFormatSpec = 'Patient %2d, Trial %2d\n'; % Description
        recordingFormatSpec = ' Fs: %6d\n Mean Hz: %6d\n High Hz: %6d\n Low Hz:  %6d\n Power: %6d\n Peak Freq: %6d\n THD: %6d\n' 
        classifierFormatSpec = '%s' % Description
    end

    methods (Access = public)
    
        function [ class ] = classifyLogReg( w, X )
            a = X * w;
            class = (1+exp(-a)).^-1;
            class =(class>0.85);
        end
        
        function [f,mx]=FFT_SCAL(app, x,fs)
            %
            %	[f,fta]=fft_scal(x,fs)
            %
            %	x = vector sampled at 1/fs
            %	fs = sampling frequency, so that fn=fs/2
            %	     is the Nyquist frequency
            %	f = frequency vector
            %	mx = Fourier amplitudes
            %=======================================
            %
            fn=fs/2;
            %t=0:1/fs:1;
            %x=sin(2*pi*t*200);
            %---------------------------------------
            %	Calculate integer nnft from the
            %	condition length(x)=2^nnft
            %---------------------------------------
            nfft=2.^(ceil(log(length(x))/log(2)));
            %---------------------------------------
            %	Take fft with two arguments
            %---------------------------------------
            fftx=fft(x,nfft);
            %---------------------------------------
            %	Retain half of fft output
            %	because of symmetry and calculate
            %	corresponding amplitudes
            %---------------------------------------
            numuniquepts=ceil((nfft+1)/2);
            fftx=fftx(1:numuniquepts);
            mx=abs(fftx);
            %mx = fftx;
            mx=mx*2;
            mx(1)=mx(1)/2;
            %if ~rem(nnft,2),
            %    mx(length(mx))=mx(length(mx))/2;
            %end,
            mx=mx/length(x);
            %----------------------------------
            %	Create the frequency vector
            %----------------------------------
            f=(0:numuniquepts-1)*2*fn/nfft;
            % plot(f,mx)
        end
        
        function [ features ] = getSoundFeatures(data, Fs )
            %UNTITLED3 Summary of this function goes here
            %   Detailed explanation goes here
            
            data = data-mean(data);
            [f, dataFFT]=FFT_SCAL(app, data,Fs);
            Datamax = max(dataFFT);
            entry = dataFFT == Datamax;
            peakFreq=f(entry);
            
            
            meanFreq = meanfreq(data, Fs);
            [bw, flo, fhi, power]= obw(data, Fs);
            
            features(1,1)=meanFreq;
            features(2,1)=flo;
            features(3,1)=fhi;
            features(4,1)=power;
            features(5,1)=peakFreq;
            features(6,1)=thd(data, Fs, 2);
            
        end

        function [class, scores] = classifyData(app, data, Fs, param_str, weights_str)
            load(param_str);
            load(weights_str);
            Xtest=getSoundFeatures(data, Fs);
            Xtest=rescale(Xtest', normalization, 0);
            Xtest=[1 Xtest];
            [class, scores] = predict(svm,Xtest);

        end
        
    end


    methods (Access = private)

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)
            %Initialize paths
            path_save = app.SavePathEditField.Value;
            path_save = strcat(path_save,datestr(datetime('now')));
            path_save = strcat(path_save, '.wav');
            
            %Load Parameters and Weights
            params = app.ParametersEditField.Value;
            weights= app.WeightsEditField.Value;
            
            load(params);
            load(weights);
            
            %disp(svm.KernelParameters);
            
            %Initialize audio recorder
            Fs = 44100;
            myMic = audiorecorder(44100, 16, 1);
            
            dur_of_trials = app.durTrialsEditField.Value;
            num_of_trials = app.numTrialsEditField.Value;
            
            
            app.CurrentStateLabel.Text = 'Found microphone...';
            app.CurrentStateLabel.Text = 'Recording...';
            
            disp((app.ManualStartStopCheckBox));
            %record data
            if (app.ManualStartStopCheckBox.Value == 0)
                recordblocking(myMic, dur_of_trials);
            else
                %app.RecordButton.Visible = 'off';
                record(myMic);
                h = figure(1);
                fig_text = uicontrol('Style', 'text',...
                   'String', 'Close the window to end recording',... %replace something with the text you want
                   'Units','normalized',...
                   'Position', [0.4 0.4 0.1 0.3]); 
                uiwait(h);
                stop(myMic);
            end
            myRecording = getaudiodata(myMic);
            %save(path_save, 'myRecording');    %save raw data
            
            %display the recording
            plot(app.currMicRecording, myRecording);
            app.CurrentStateLabel.Text = 'Done recording...';
            app.CurrentStateLabel.Text = 'Processing...';
            
            %Data Processing
            T = 1/Fs;
            L = myMic.TotalSamples;
            t = (0:L-1)*T;
            Y = fft(myRecording);
            P2 = abs(Y/L);
            P1 = P2(1:L/2+1);
            P1(2:end-1) = 2*P1(2:end-1);
            f = Fs*(0:(L/2))/L;
            
            Xtest=getSoundFeatures(myRecording, Fs);
            Xtest=rescale(Xtest', normalization, 0);
            Xtest=[1 Xtest];
            
            [class,scores] = predict(svm,Xtest);
            
            
            %display the recording
            plot(app.currMicRecording, t, myRecording);
            
            %find peaks
            %[pks locs] = findpeaks(P1, f, 'MinPeakProminence', 0);
            %index = max(pks);
            %peak_freq = f(locs);
            
            [val, ind] = max(P1);
            max_freq = f(ind);
            
            %Print Data
            mean_freq = Xtest(1);
            flo = Xtest(2);
            fhi = Xtest(3);
            power = Xtest(4);
            peak_freq = Xtest(5);
            thd_val = Xtest(6);
            
            app.TextArea.Value = sprintf(app.recordingFormatSpec, Fs, mean_freq, flo, fhi, power, peak_freq, thd_val);
            plot(app.fftGraph, f, P1);
            app.CurrentStateLabel.Text = 'Done!';
            
            %classification
            if (class == 1)
                app.PatientHealthEditField.Value = 'Healthy';
            else
                app.PatientHealthEditField.Value = 'Not Healthy';
            end            
            app.ConfidenceBoundsEditField.Value = strcat(num2str(scores(1)), ', ', num2str(scores(2)));
            
            %Save the sound file
            audiowrite(path_save,myRecording,Fs);
            app.LoadPathEditField.Value = path_save;
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            %path_load = '/Users/Mark/Documents/MATLAB/DopplerZamCode/Data/patients/training/healthy5.m4a' %notHealthy5.m4a';
            path_load = '/Users/Mark/Documents/MATLAB/DopplerZamCode/Data/patients/testing/notHealthy5.m4a'
            %path_load = app.LoadPathEditField.Value;
            [myRecording, Fs] = audioread(path_load);
            
            %Load Parameters and Weights
            params = app.ParametersEditField.Value;
            weights= app.WeightsEditField.Value;
            
            load(params);
            load(weights);
            
            %play audio
            app.CurrentStateLabel.Text = 'Playing Audio...';
            sound(myRecording, Fs);
            
            app.CurrentStateLabel.Text = 'Processing...';
            
            %Data Processing
            T = 1/Fs;
            L = numel(myRecording);
            t = (0:L-1)*T;
            Y = fft(myRecording);
            P2 = abs(Y/L);
            P1 = P2(1:L/2+1);
            P1(2:end-1) = 2*P1(2:end-1);
            f = Fs*(0:(L/2))/L;
            
            Xtest=getSoundFeatures(myRecording, Fs);
            Xtest=rescale(Xtest', normalization, 0);
            Xtest=[1 Xtest];
            
            [class,scores] = predict(svm,Xtest);
            
            
            %display the recording
            plot(app.currMicRecording, t, myRecording);
            
            
            [val, ind] = max(P1);
            max_freq = f(ind);
            
            %classification
            if (class == 1)
                app.PatientHealthEditField.Value = 'Healthy';
            else
                app.PatientHealthEditField.Value = 'Not Healthy';
            end
            %app.PatientHealthEditField.Value = int2str(class);
            app.ConfidenceBoundsEditField.Value = strcat(num2str(scores(1)), ', ', num2str(scores(2)));
            
            %Print Data
            output_str = 'Patient Trial #';
            mean_freq = Xtest(1);
            flo = Xtest(2);
            fhi = Xtest(3);
            power = Xtest(4);
            peak_freq = Xtest(5);
            thd_val = Xtest(6);
            
            app.TextArea.Value = sprintf(app.recordingFormatSpec, Fs, mean_freq, flo, fhi, power, peak_freq, thd_val);
            plot(app.fftGraph, f, P1);
            app.CurrentStateLabel.Text = 'Done!';
            
        end

        % Callback function
        function ManualStartStopCheckBoxValueChanged(app, event)
            value = app.ManualStartStopCheckBox.Value;
            %if (value == 1)
            %    app.RecordButton.Visible = 'off';
            %    app.RecordButton.Enable = 'off';
            %else
            %    app.RecordButton.Visible = 'on';
            %    app.RecordButton.Enable = 'on';
            %end
        end

        % Value changed function: ViewCodeEditField
        function codeEntered(app, event)
            value = app.ViewCodeEditField.Value;
            code = 'aquino lab';
            if (strcmp(value, code))
                app.SavePathEditField.Enable = 'on'
                app.LoadPathEditField.Enable = 'on';
                app.ParametersEditField.Enable = 'on';
                app.WeightsEditField.Enable = 'on';
            else
                app.SavePathEditField.Enable = 'off'
                app.LoadPathEditField.Enable = 'off';
                app.ParametersEditField.Enable = 'off';
                app.WeightsEditField.Enable = 'off';
            end
                
        end

        % Value changed function: TrainCodeEditField
        function trainCodeEntered(app, event)
            value = app.TrainCodeEditField.Value;
            train_code = 'aquino lab train';
            if strcmp(value, train_code)
                app.DataFolderPathEditField.Enable = 'on';
                app.ParametersPathEditField.Enable = 'on';
                app.ClicktoTrainButton.Enable = 'on'
            else
                app.DataFolderPathEditField.Enable = 'off';
                app.ParametersPathEditField.Enable = 'off';
                app.ClicktoTrainButton.Enable = 'off'
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.currMicRecording = uiaxes(app.UIFigure);
            title(app.currMicRecording, 'Current Microphone Reading');
            xlabel(app.currMicRecording, 'Time (s)');
            ylabel(app.currMicRecording, 'Amplitude');
            app.RecordButton = uibutton(app.UIFigure, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Text = 'Record';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.CurrentStateLabel = uilabel(app.UIFigure);
            app.CurrentStateLabel.Text = 'Current State';
            app.fftGraph = uiaxes(app.UIFigure);
            title(app.fftGraph, 'FFT Graph');
            xlabel(app.fftGraph, 'Frequency (Hz)');
            ylabel(app.fftGraph, 'Amplitude');
            app.TabGroup = uitabgroup(app.UIFigure);
            app.OptionsTab = uitab(app.TabGroup);
            app.OptionsTab.Title = 'Options';
            app.OptionsTab.Units = 'pixels';
            app.ManualStartStopCheckBox = uicheckbox(app.OptionsTab);
            app.ManualStartStopCheckBox.Text = 'Manual Start/Stop';
            app.numTrialsEditField_2Label = uilabel(app.OptionsTab);
            app.numTrialsEditField_2Label.Text = 'Number of Trials';
            app.numTrialsEditField = uieditfield(app.OptionsTab, 'numeric');
            app.numTrialsEditField.Limits = [0 10];
            app.numTrialsEditField.Value = 5;
            app.durTrialsEditField_2Label = uilabel(app.OptionsTab);
            app.durTrialsEditField_2Label.Text = 'Duration of Trials';
            app.durTrialsEditField = uieditfield(app.OptionsTab, 'numeric');
            app.durTrialsEditField.Limits = [0 30];
            app.durTrialsEditField.Value = 5;
            app.AdvancedTab = uitab(app.TabGroup);
            app.AdvancedTab.Title = 'Advanced';
            app.AdvancedTab.Units = 'pixels';
            app.SavePathEditField_2Label = uilabel(app.AdvancedTab);
            app.SavePathEditField_2Label.Enable = 'off';
            app.SavePathEditField_2Label.Text = 'Save Path';
            app.SavePathEditField = uieditfield(app.AdvancedTab, 'text');
            app.SavePathEditField.Enable = 'off';
            app.SavePathEditField.Value = '/Users/Mark/Documents/MATLAB/DopplerZamCode/Data/new_recordings/';
            app.LoadPathEditField_2Label = uilabel(app.AdvancedTab);
            app.LoadPathEditField_2Label.Enable = 'off';
            app.LoadPathEditField_2Label.Text = 'Load Path';
            app.LoadPathEditField = uieditfield(app.AdvancedTab, 'text');
            app.LoadPathEditField.Enable = 'off';
            app.LoadPathEditField.Value = '/Users/Mark/Documents/MATLAB/DopplerZamCode/Data/new_recordings/';
            app.ParametersEditField_2Label = uilabel(app.AdvancedTab);
            app.ParametersEditField_2Label.Enable = 'off';
            app.ParametersEditField_2Label.Text = 'Parameters';
            app.ParametersEditField = uieditfield(app.AdvancedTab, 'text');
            app.ParametersEditField.Enable = 'off';
            app.ParametersEditField.Value = '/Users/Mark/Documents/MATLAB/DopplerZamCode/MatlabCode/WorkingCode/parameters.mat';
            app.WeightsEditField_2Label = uilabel(app.AdvancedTab);
            app.WeightsEditField_2Label.Enable = 'off';
            app.WeightsEditField_2Label.Text = 'Weights';
            app.WeightsEditField = uieditfield(app.AdvancedTab, 'text');
            app.WeightsEditField.Enable = 'off';
            app.WeightsEditField.Value = '/Users/Mark/Documents/MATLAB/DopplerZamCode/MatlabCode/WorkingCode/weights.mat';
            app.ViewCodeEditFieldLabel = uilabel(app.AdvancedTab);
            app.ViewCodeEditFieldLabel.Text = 'View Code';
            app.ViewCodeEditField = uieditfield(app.AdvancedTab, 'text');
            app.ViewCodeEditField.ValueChangedFcn = createCallbackFcn(app, @codeEntered, true);
            app.TrainingTab = uitab(app.TabGroup);
            app.TrainingTab.Title = 'Training';
            app.TrainingTab.Units = 'pixels';
            app.DataFolderPathEditFieldLabel = uilabel(app.TrainingTab);
            app.DataFolderPathEditFieldLabel.Text = 'Data Folder Path';
            app.DataFolderPathEditField = uieditfield(app.TrainingTab, 'text');
            app.DataFolderPathEditField.Enable = 'off';
            app.TrainCodeEditFieldLabel = uilabel(app.TrainingTab);
            app.TrainCodeEditFieldLabel.Text = 'Train Code';
            app.TrainCodeEditField = uieditfield(app.TrainingTab, 'text');
            app.TrainCodeEditField.ValueChangedFcn = createCallbackFcn(app, @trainCodeEntered, true);
            app.ParametersPathEditFieldLabel = uilabel(app.TrainingTab);
            app.ParametersPathEditFieldLabel.Text = 'Parameters Path';
            app.ParametersPathEditField = uieditfield(app.TrainingTab, 'text');
            app.ParametersPathEditField.Enable = 'off';
            app.ClicktoTrainButton = uibutton(app.TrainingTab, 'push');
            app.ClicktoTrainButton.Enable = 'off';
            app.ClicktoTrainButton.Text = 'Click to Train';
            app.TrainStatusLabel = uilabel(app.TrainingTab);
            app.TrainStatusLabel.Text = 'Train Status';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.ResultsTab = uitab(app.TabGroup2);
            app.ResultsTab.Title = 'Results';
            app.ResultsTab.Units = 'pixels';
            app.PatientHealthEditFieldLabel = uilabel(app.ResultsTab);
            app.PatientHealthEditFieldLabel.Text = 'Patient Health';
            app.PatientHealthEditField = uieditfield(app.ResultsTab, 'text');
            app.PatientHealthEditField.Editable = 'off';
            app.PatientHealthEditField.Value = 'n/a';
            app.ConfidenceBoundsEditFieldLabel = uilabel(app.ResultsTab);
            app.ConfidenceBoundsEditFieldLabel.Text = 'Confidence Bounds';
            app.ConfidenceBoundsEditField = uieditfield(app.ResultsTab, 'text');
            app.ConfidenceBoundsEditField.Editable = 'off';
            app.ConfidenceBoundsEditField.Value = 'n/a';
            app.MoreTab = uitab(app.TabGroup2);
            app.MoreTab.Title = 'More';
            app.MoreTab.Units = 'pixels';
            app.TextArea = uitextarea(app.MoreTab);
            app.TextArea.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = UltrasoundClassificationv5()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end