classdef UltrasoundClassificationv6 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        currMicRecording                matlab.ui.control.UIAxes
        RecordButton                    matlab.ui.control.Button
        PlayButton                      matlab.ui.control.Button
        CurrentStateLabel               matlab.ui.control.Label
        fftGraph                        matlab.ui.control.UIAxes
        TabGroup                        matlab.ui.container.TabGroup
        OptionsTab                      matlab.ui.container.Tab
        ManualStartStopCheckBox         matlab.ui.control.CheckBox
        numTrialsEditField_2Label       matlab.ui.control.Label
        numTrialsEditField              matlab.ui.control.NumericEditField
        durTrialsEditField_2Label       matlab.ui.control.Label
        durTrialsEditField              matlab.ui.control.NumericEditField
        AdvancedTab                     matlab.ui.container.Tab
        SavePathEditField_2Label        matlab.ui.control.Label
        SavePathEditField               matlab.ui.control.EditField
        LoadPathEditField_2Label        matlab.ui.control.Label
        LoadPathEditField               matlab.ui.control.EditField
        ParametersEditField_2Label      matlab.ui.control.Label
        ParametersEditField             matlab.ui.control.EditField
        WeightsEditField_2Label         matlab.ui.control.Label
        WeightsEditField                matlab.ui.control.EditField
        ViewCodeEditFieldLabel          matlab.ui.control.Label
        ViewCodeEditField               matlab.ui.control.EditField
        TrainingTab                     matlab.ui.container.Tab
        ClickToTrainButton              matlab.ui.control.Button
        KernelFunctionDropDownLabel     matlab.ui.control.Label
        KernelFunctionDropDown          matlab.ui.control.DropDown
        BoxConstraintDropDownLabel      matlab.ui.control.Label
        BoxConstraintDropDown           matlab.ui.control.DropDown
        TrainingStatusLabel             matlab.ui.control.Label
        TrainingDirectoryEditFieldLabel  matlab.ui.control.Label
        TrainingDirectoryEditField      matlab.ui.control.EditField
        TabGroup2                       matlab.ui.container.TabGroup
        ResultsTab                      matlab.ui.container.Tab
        PatientHealthEditFieldLabel     matlab.ui.control.Label
        PatientHealthEditField          matlab.ui.control.EditField
        ConfidenceBoundsEditFieldLabel  matlab.ui.control.Label
        ConfidenceBoundsEditField       matlab.ui.control.EditField
        MoreTab                         matlab.ui.container.Tab
        TextArea                        matlab.ui.control.TextArea
        StatsTab                        matlab.ui.container.Tab
    end


    methods (Access = private)

        % Button pushed function: RecordButton
        function RecordButtonPushed(app, event)
            %Initialize paths
            path_save = app.SavePathEditField.Value;
            
            %Load Parameters and Weights
            load(app.ParametersEditField.Value);
            load(app.WeightsEditField.Value);
            
            %Initialize audio recorder
            Fs = 44100;            
            dur_of_trials = app.durTrialsEditField.Value;
            num_of_trials = app.numTrialsEditField.Value;
            manualFlag = app.ManualStartStopCheckBox.Value;
            
            app.CurrentStateLabel.Text = 'Found microphone...';
            app.CurrentStateLabel.Text = 'Recording...';
            
            [myRecording, path_save, L] = recordData(path_save, Fs, dur_of_trials, num_of_trials, manualFlag);
            
           
            app.CurrentStateLabel.Text = 'Done recording...';
            app.CurrentStateLabel.Text = 'Processing...';
            

            [class, Xtest] = processData( myRecording, Fs, svm, normalization );
            
            
            %display the recording
            [r c] = size(myRecording);
            hold on
            for i = 1:r
                %Data Processing
                T = 1/Fs;
                t = (0:L(i,:)-1)*T;
                Y = fft(myRecording(i,:));
                P2 = abs(Y/L(i,:));
                P1 = P2(1:L(i,:)/2+1);
                P1(2:end-1) = 2*P1(2:end-1);
                f = Fs*(0:(L(i,:)/2))/L(i,:);
                plot(app.currMicRecording, myRecording(i,:));
                plot(app.fftGraph, f, P1);
            end
            hold off
%             %Print Data
%             mean_freq = Xtest(2,:);
%             flo = Xtest(3,:);
%             fhi = Xtest(4,:);
%             power = Xtest(5,:);
%             peak_freq = Xtest(6,:);
%             thd_val = Xtest(7,:);
%             
%             app.TextArea.Value = sprintf(app.recordingFormatSpec, Fs, mean_freq, flo, fhi, power, peak_freq, thd_val);
            
            app.CurrentStateLabel.Text = 'Done!';
            
%             %classification
%             if (class(1) == 1)
%                 app.PatientHealthEditField.Value = 'Healthy';
%             else
%                 app.PatientHealthEditField.Value = 'Not Healthy';
%             end            
            app.ConfidenceBoundsEditField.Value = 'N/A';
            
            app.LoadPathEditField.Value = path_save;
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            path_load = '/Users/Mark/Documents/MATLAB/DopplerZamCode/Data/patients/training/healthy5.m4a' %notHealthy5.m4a';
            %path_load = '/Users/Mark/Documents/MATLAB/DopplerZamCode/Data/patients/testing/notHealthy5.m4a'
            %path_load = app.LoadPathEditField.Value;
            [myRecording, Fs] = audioread(path_load);
            
            %Load Parameters and Weights
            params = app.ParametersEditField.Value;
            weights= app.WeightsEditField.Value;
            
            load(params);
            load(weights);
            
            %play audio
            app.CurrentStateLabel.Text = 'Playing Audio...';
            sound(myRecording, Fs);
            
            app.CurrentStateLabel.Text = 'Processing...';
            
            %Data Processing
            T = 1/Fs;
            L = numel(myRecording);
            t = (0:L-1)*T;
            Y = fft(myRecording);
            P2 = abs(Y/L);
            P1 = P2(1:L/2+1);
            P1(2:end-1) = 2*P1(2:end-1);
            f = Fs*(0:(L/2))/L;
            
            Xtest=getSoundFeatures(myRecording, Fs);
            
            Xtest=rescale(Xtest', normalization, 0);
            Xtest=[1 Xtest];
            
            [class,scores] = predict(svm,Xtest);
            
            
            %display the recording
            plot(app.currMicRecording, t, myRecording);
            
            %find peaks
            %[pks locs] = findpeaks(P1, f, 'MinPeakProminence', 0);
            %index = max(pks);
            %peak_freq = f(locs);
            
            [val, ind] = max(P1);
            max_freq = f(ind);
            
            %classification
            if (class == 1)
                app.PatientHealthEditField.Value = 'Healthy';
            else
                app.PatientHealthEditField.Value = 'Not Healthy';
            end
            %app.PatientHealthEditField.Value = int2str(class);
            app.ConfidenceBoundsEditField.Value = strcat(num2str(scores(1)), ', ', num2str(scores(2)));
            
            %Print Data
            app.SamplingFreqHzEditField.Value = int2str(Fs);
            app.MaxFreqHzEditField.Value = int2str(max_freq);
            app.PeakFreqHzEditField.Value = 'not implemented';%int2str(peak_freq);
            plot(app.fftGraph, f, P1);
            app.CurrentStateLabel.Text = 'Done!';
            
        end

        % Callback function
        function ManualStartStopCheckBoxValueChanged(app, event)
            value = app.ManualStartStopCheckBox.Value;
            %if (value == 1)
            %    app.RecordButton.Visible = 'off';
            %    app.RecordButton.Enable = 'off';
            %else
            %    app.RecordButton.Visible = 'on';
            %    app.RecordButton.Enable = 'on';
            %end
        end

        % Value changed function: ViewCodeEditField
        function codeEntered(app, event)
            value = app.ViewCodeEditField.Value;
            code = 'gross';
            if (strcmp(value, code))
                app.SavePathEditField.Enable = 'on'
                app.LoadPathEditField.Enable = 'on';
                app.ParametersEditField.Enable = 'on';
                app.WeightsEditField.Enable = 'on';
            else
                app.SavePathEditField.Enable = 'off'
                app.LoadPathEditField.Enable = 'off';
                app.ParametersEditField.Enable = 'off';
                app.WeightsEditField.Enable = 'off';
            end
                
        end

        % Button pushed function: ClickToTrainButton
        function TrainButtonPushed(app, event)
            kernelFunctionValue = app.KernelFunctionDropDown.value;
            boxConstraintValue = app.BoxConstraintDropDown.value;
            trainDataDirPath = app.TrainingDirectoryEditField;
            app.TrainingStatusLabel.value = 'Starting to train data...';
            
            trainAlgorithm(trainDataDirPath, kernelFunctionValue, boxConstraintValue);
            %call the training code passing the arguments...
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.currMicRecording = uiaxes(app.UIFigure);
            title(app.currMicRecording, 'Current Microphone Reading');
            xlabel(app.currMicRecording, 'Time (s)');
            ylabel(app.currMicRecording, 'Amplitude');
            app.RecordButton = uibutton(app.UIFigure, 'push');
            app.RecordButton.ButtonPushedFcn = createCallbackFcn(app, @RecordButtonPushed, true);
            app.RecordButton.Text = 'Record';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.CurrentStateLabel = uilabel(app.UIFigure);
            app.CurrentStateLabel.Text = 'Current State';
            app.fftGraph = uiaxes(app.UIFigure);
            title(app.fftGraph, 'FFT Graph');
            xlabel(app.fftGraph, 'Frequency (Hz)');
            ylabel(app.fftGraph, 'Amplitude');
            app.TabGroup = uitabgroup(app.UIFigure);
            app.OptionsTab = uitab(app.TabGroup);
            app.OptionsTab.Title = 'Options';
            app.OptionsTab.Units = 'pixels';
            app.ManualStartStopCheckBox = uicheckbox(app.OptionsTab);
            app.ManualStartStopCheckBox.Text = 'Manual Start/Stop';
            app.numTrialsEditField_2Label = uilabel(app.OptionsTab);
            app.numTrialsEditField_2Label.Text = 'Number of Trials';
            app.numTrialsEditField = uieditfield(app.OptionsTab, 'numeric');
            app.numTrialsEditField.Limits = [0 10];
            app.numTrialsEditField.Value = 1;
            app.durTrialsEditField_2Label = uilabel(app.OptionsTab);
            app.durTrialsEditField_2Label.Text = 'Duration of Trials';
            app.durTrialsEditField = uieditfield(app.OptionsTab, 'numeric');
            app.durTrialsEditField.Limits = [0 30];
            app.durTrialsEditField.Value = 5;
            app.AdvancedTab = uitab(app.TabGroup);
            app.AdvancedTab.Title = 'Advanced';
            app.AdvancedTab.Units = 'pixels';
            app.SavePathEditField_2Label = uilabel(app.AdvancedTab);
            app.SavePathEditField_2Label.Enable = 'off';
            app.SavePathEditField_2Label.Text = 'Save Path';
            app.SavePathEditField = uieditfield(app.AdvancedTab, 'text');
            app.SavePathEditField.Enable = 'off';
            app.SavePathEditField.Value = '/Users/Mark/Documents/MATLAB/SoundFiles/sound';
            app.LoadPathEditField_2Label = uilabel(app.AdvancedTab);
            app.LoadPathEditField_2Label.Enable = 'off';
            app.LoadPathEditField_2Label.Text = 'Load Path';
            app.LoadPathEditField = uieditfield(app.AdvancedTab, 'text');
            app.LoadPathEditField.Enable = 'off';
            app.LoadPathEditField.Value = '/Users/Mark/Documents/MATLAB/SoundFiles/sound';
            app.ParametersEditField_2Label = uilabel(app.AdvancedTab);
            app.ParametersEditField_2Label.Enable = 'off';
            app.ParametersEditField_2Label.Text = 'Parameters';
            app.ParametersEditField = uieditfield(app.AdvancedTab, 'text');
            app.ParametersEditField.Enable = 'off';
            app.ParametersEditField.Value = '/Users/Mark/Documents/MATLAB/DopplerZamCode/MatlabCode/WorkingCode/parameters.mat';
            app.WeightsEditField_2Label = uilabel(app.AdvancedTab);
            app.WeightsEditField_2Label.Enable = 'off';
            app.WeightsEditField_2Label.Text = 'Weights';
            app.WeightsEditField = uieditfield(app.AdvancedTab, 'text');
            app.WeightsEditField.Enable = 'off';
            app.WeightsEditField.Value = '/Users/Mark/Documents/MATLAB/DopplerZamCode/MatlabCode/WorkingCode/weights.mat';
            app.ViewCodeEditFieldLabel = uilabel(app.AdvancedTab);
            app.ViewCodeEditFieldLabel.Text = 'View Code';
            app.ViewCodeEditField = uieditfield(app.AdvancedTab, 'text');
            app.ViewCodeEditField.ValueChangedFcn = createCallbackFcn(app, @codeEntered, true);
            app.TrainingTab = uitab(app.TabGroup);
            app.TrainingTab.Title = 'Training';
            app.TrainingTab.Units = 'pixels';
            app.ClickToTrainButton = uibutton(app.TrainingTab, 'push');
            app.ClickToTrainButton.ButtonPushedFcn = createCallbackFcn(app, @TrainButtonPushed, true);
            app.ClickToTrainButton.Text = 'Click To Train';
            app.KernelFunctionDropDownLabel = uilabel(app.TrainingTab);
            app.KernelFunctionDropDownLabel.Text = 'Kernel Function';
            app.KernelFunctionDropDown = uidropdown(app.TrainingTab);
            app.KernelFunctionDropDown.Items = {'RBF', 'Linear', 'Polynomial', 'Option 4'};
            app.KernelFunctionDropDown.Value = 'RBF';
            app.BoxConstraintDropDownLabel = uilabel(app.TrainingTab);
            app.BoxConstraintDropDownLabel.Text = 'Box Constraint';
            app.BoxConstraintDropDown = uidropdown(app.TrainingTab);
            app.BoxConstraintDropDown.Items = {'1', 'Option 2', 'Option 3', 'Option 4'};
            app.BoxConstraintDropDown.Editable = 'on';
            app.BoxConstraintDropDown.Value = '1';
            app.TrainingStatusLabel = uilabel(app.TrainingTab);
            app.TrainingStatusLabel.Text = 'Training Status';
            app.TrainingDirectoryEditFieldLabel = uilabel(app.TrainingTab);
            app.TrainingDirectoryEditFieldLabel.Text = 'Training Directory';
            app.TrainingDirectoryEditField = uieditfield(app.TrainingTab, 'text');
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.ResultsTab = uitab(app.TabGroup2);
            app.ResultsTab.Title = 'Results';
            app.ResultsTab.Units = 'pixels';
            app.PatientHealthEditFieldLabel = uilabel(app.ResultsTab);
            app.PatientHealthEditFieldLabel.Text = 'Patient Health';
            app.PatientHealthEditField = uieditfield(app.ResultsTab, 'text');
            app.PatientHealthEditField.Editable = 'off';
            app.PatientHealthEditField.Value = 'n/a';
            app.ConfidenceBoundsEditFieldLabel = uilabel(app.ResultsTab);
            app.ConfidenceBoundsEditFieldLabel.Text = 'Confidence Bounds';
            app.ConfidenceBoundsEditField = uieditfield(app.ResultsTab, 'text');
            app.ConfidenceBoundsEditField.Editable = 'off';
            app.ConfidenceBoundsEditField.Value = 'n/a';
            app.MoreTab = uitab(app.TabGroup2);
            app.MoreTab.Title = 'More';
            app.MoreTab.Units = 'pixels';
            app.TextArea = uitextarea(app.MoreTab);
            app.StatsTab = uitab(app.TabGroup2);
            app.StatsTab.Title = 'Stats';
            app.StatsTab.Units = 'pixels';
        end
    end

    methods (Access = public)

        % Construct app
        function app = UltrasoundClassificationv6()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end